classdef Module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddModuleUIFigure         matlab.ui.Figure
        EnterUIDEditFieldLabel_2  matlab.ui.control.Label
        ip                        matlab.ui.control.EditField
        SelectAxisDropDownLabel   matlab.ui.control.Label
        SelectAxisDropDown        matlab.ui.control.DropDown
        UITable                   matlab.ui.control.Table
        EnterUIDEditFieldLabel_3  matlab.ui.control.Label
        uid                       matlab.ui.control.EditField
        AddModuleButton           matlab.ui.control.Button
        ConnectButton             matlab.ui.control.Button
        EnterUIDEditFieldLabel_4  matlab.ui.control.Label
        port                      matlab.ui.control.NumericEditField
        acceldataButton           matlab.ui.control.Button
        homeButton                matlab.ui.control.Button
    end


    properties (Access = public)
        count=0; % module count
        matval=[];
        sometext{};
        host
    end


    methods (Access = private)

        % Button pushed function: AddModuleButton
        function AddModuleButtonPushed(app, event)
            app.count=app.count+1;
            uidval = app.uid.Value;
            ipval = app.ip.Value;
            portval = app.port.Value;
            axis = app.SelectAxisDropDown.Value;
            %app.matval=[app.count uidval ipval axis];
            app.sometext{app.count,1}=app.count;
            app.sometext{app.count,2}=uidval;
            app.sometext{app.count,3}=ipval;
            app.sometext{app.count,4}=portval;
            app.sometext{app.count,5}=axis;
            
            %app.sometext;
            app.UITable.Data = [app.sometext(:,1) ,app.sometext(:,2) ,app.sometext(:,3) ,app.sometext(:,4),app.sometext(:,5)];
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
        sz=size(app.sometext);
        for c=1:sz(1,1)
            %ipadd=app.sometext(c,3);
            %ipout=ipadd{1};
            %portadd=app.sometext(c,4);
            %app.host=app.sometext(c,2);
            app.host=udp('192.168.1.64',1808);
            fopen(app.host);
        end
        end

        % Button pushed function: acceldataButton
        function acceldataButtonPushed(app, event)
            a=fread(app.host);
            disp(a);
        end

        % Button pushed function: homeButton
        function homeButtonPushed(app, event)
            fwrite(app.host,'1:200');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddModuleUIFigure = uifigure;
            app.AddModuleUIFigure.Name = 'Add Module';
            app.EnterUIDEditFieldLabel_2 = uilabel(app.AddModuleUIFigure);
            app.EnterUIDEditFieldLabel_2.Text = 'Enter IP';
            app.ip = uieditfield(app.AddModuleUIFigure, 'text');
            app.SelectAxisDropDownLabel = uilabel(app.AddModuleUIFigure);
            app.SelectAxisDropDownLabel.Text = 'Select Axis';
            app.SelectAxisDropDown = uidropdown(app.AddModuleUIFigure);
            app.SelectAxisDropDown.Items = {'X', 'Y', 'Z', ''};
            app.SelectAxisDropDown.Value = '';
            app.UITable = uitable(app.AddModuleUIFigure);
            app.UITable.ColumnName = {'Sr.No'; 'UID'; 'IP'; 'Port'; 'Axis'};
            app.UITable.RowName = {};
            app.EnterUIDEditFieldLabel_3 = uilabel(app.AddModuleUIFigure);
            app.EnterUIDEditFieldLabel_3.Text = 'UID';
            app.uid = uieditfield(app.AddModuleUIFigure, 'text');
            app.AddModuleButton = uibutton(app.AddModuleUIFigure, 'push');
            app.AddModuleButton.ButtonPushedFcn = createCallbackFcn(app, @AddModuleButtonPushed, true);
            app.AddModuleButton.Text = 'Add Module';
            app.ConnectButton = uibutton(app.AddModuleUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.EnterUIDEditFieldLabel_4 = uilabel(app.AddModuleUIFigure);
            app.EnterUIDEditFieldLabel_4.Text = 'Enter Port';
            app.port = uieditfield(app.AddModuleUIFigure, 'numeric');
            app.acceldataButton = uibutton(app.AddModuleUIFigure, 'push');
            app.acceldataButton.ButtonPushedFcn = createCallbackFcn(app, @acceldataButtonPushed, true);
            app.acceldataButton.Text = 'acceldata';
            app.homeButton = uibutton(app.AddModuleUIFigure, 'push');
            app.homeButton.ButtonPushedFcn = createCallbackFcn(app, @homeButtonPushed, true);
            app.homeButton.Text = 'home';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Module

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddModuleUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddModuleUIFigure)
        end
    end
end